classdef appharmo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        EnergyEditField              matlab.ui.control.EditField
        EnergyEditFieldLabel         matlab.ui.control.Label
        SimulateButton               matlab.ui.control.Button
        aWidthofwellEditField        matlab.ui.control.EditField
        aWidthofwellEditFieldLabel   matlab.ui.control.Label
        mparticlemassEditField       matlab.ui.control.EditField
        mparticlemassEditFieldLabel  matlab.ui.control.Label
        INPUTLabel                   matlab.ui.control.Label
        HARMONICOSCILLATORLabel      matlab.ui.control.Label
        UIAxes2                      matlab.ui.control.UIAxes
        UIAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)

m=str2double(app.mparticlemassEditField.Value); 
h=1973; 
%in ev unit, hc=1973eVA; 0.511MeV/c^2

a=str2double(app.aWidthofwellEditField.Value);
n=500; dx=a/n;
x=[(-a/2):dx:(a/2)];
%now function for potential;

V=@(x) x.^2;  %for harmonic oscillator


for i=1:n-1
    xi=x(i+1);
    A(i,i)=2*(1+(m*dx*dx/(h*h))*V(xi));
   if i<n-1 
    A(i,i+1)=-1;
    A(i+1,i)=-1;
   end
end

% Compute eigenvalues and eigenvectors of A
[vecs, vals] = eig(A);
% Elevel= diag(vals);
[M N]=size(vecs);
psi=zeros(M,N);

% Normalize wavefunctions
for i=1:N
psi(:,i) = vecs(:,i);
psi(:,i) = vecs(:,i)./ sqrt(dx*sum(abs(psi(:,i)).^2));
end

% % Print eigenvalues
% disp('Eigenvalues:');
% disp(diag(vals));
% 
% % Print eigenvectors
% disp('Eigenvectors:');
% disp(vecs);

%for psi in every point

psi0=[0;vecs(:,1);0];
psi1=[0;vecs(:,2);0];
psi2=[0;vecs(:,3);0];
psi3=[0;vecs(:,4);0];


plot(app.UIAxes,x,psi0,x,psi1,x,psi2,x,psi3);
xlabel(app.UIAxes, 'Position (m)');
ylabel(app.UIAxes, 'Position');


%These are the wave function solution. For probability density

z=max(psi0.^2)+0.01;
plot(app.UIAxes2,x,psi0.^2,x,psi1.^2+z,x,psi2.^2+2*z,x,psi3.^2+3*z);

hold(app.UIAxes2,'on');
plot (app.UIAxes2,x,V(x)/350);
xlabel(app.UIAxes2, 'Position (m)');
ylabel(app.UIAxes2, 'Probability density');
hold(app.UIAxes2,'off');

% %Expectation value
%  x_exp = real(sum(conj(psi).*x.*psi)*dx);
% % x_exp = trapz(x, abs(psi).^2 .* x);
% disp(x_exp(:,1))
% p_exp =real(sum(conj(psi).*fftshift(-1i*h*fft(psi))/(2*pi).*psi)*dx);
% disp(p_exp(:,1))
% %  E_expect = real(sum(conj(psi).*(H*psi))*dx);
% E_exp = trapz(x, abs(psi).^2 .* diag(vals));
% disp(E_exp(:,1))

% %Energy_level_plot
% figure;
% hold on
% plot(x,E);
% %figure;
% plot([x(1), x(end)], [E(10)*1e3, E(10)*1e3], 'k-', 'LineWidth', 2);
% xlabel('Position (m)');
% ylabel('Energy level');


%For energy

E=eig(A)*h*h/(2*m*dx*dx);
app.EnergyEditField.Value=num2str(E(1,1));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.HARMONICOSCILLATORLabel = uilabel(app.UIFigure);
            app.HARMONICOSCILLATORLabel.Text = 'HARMONIC OSCILLATOR';
            app.INPUTLabel = uilabel(app.UIFigure);
            app.INPUTLabel.Text = 'INPUT';
            app.mparticlemassEditFieldLabel = uilabel(app.UIFigure);
            app.mparticlemassEditFieldLabel.Text = 'm (particle mass)';
            app.mparticlemassEditField = uieditfield(app.UIFigure, 'text');
            app.aWidthofwellEditFieldLabel = uilabel(app.UIFigure);
            app.aWidthofwellEditFieldLabel.Text = 'a (Width of well)';
            app.aWidthofwellEditField = uieditfield(app.UIFigure, 'text');
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.EnergyEditFieldLabel = uilabel(app.UIFigure);
            app.EnergyEditFieldLabel.Text = 'Energy';
            app.EnergyEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appharmo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end