classdef appinf < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        ExpectationvalueofmomentumEditField  matlab.ui.control.EditField
        ExpectationvalueofmomentumEditFieldLabel  matlab.ui.control.Label
        ExpectationvalueofEnergyEditField  matlab.ui.control.EditField
        ExpectationvalueofEnergyEditFieldLabel  matlab.ui.control.Label
        ExpectationvalueofpositionEditField  matlab.ui.control.EditField
        ExpectationvalueofpositionEditFieldLabel  matlab.ui.control.Label
        SimulateButton            matlab.ui.control.Button
        INPUTLabel                matlab.ui.control.Label
        nEditField                matlab.ui.control.EditField
        nEditFieldLabel           matlab.ui.control.Label
        LEditField                matlab.ui.control.EditField
        LEditFieldLabel           matlab.ui.control.Label
        V0EditField               matlab.ui.control.EditField
        V0EditFieldLabel          matlab.ui.control.Label
        SQUAREPOTENTIALWELLLabel  matlab.ui.control.Label
        UIAxes3                   matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
        UIAxes                    matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % Parameters
hbar = 1.0545718e-34;           % Planck's constant / 2*pi
m = 9.10938356e-31;             % Mass of electron
L =str2double(app.LEditField.Value);                       % Length of well
V0 = str2double(app.V0EditField.Value)*1e-19;           % Depth of well
a = L/4;                        % Width of well
x = linspace(0,L,1000)';        % Spatial grid
dx = x(2)-x(1);                 % Spatial step size
n=str2double(app.nEditField.Value);

% Potential energy function
V = zeros(size(x));
V(x<=a | x>=L-a) = V0;

% Kinetic energy operator
T = -hbar^2/(2*m) * (diag(-2*ones(1,length(x))) + diag(ones(1,length(x)-1),1) + diag(ones(1,length(x)-1),-1)) / dx^2;

% Hamiltonian operator
H = T + diag(V);

% Solve for eigenvalues and eigenvectors
[Vpsi,E] = eig(H);
E= diag(E);
[M N]=size(Vpsi);
psi=zeros(M,N);
% Normalize wavefunctions
for i=1:N
psi(:,i) = Vpsi(:,i);
psi(:,i) = Vpsi(:,i)./ sqrt(dx*sum(abs(psi(:,i)).^2));
end


% % Calculate expectation values
% P = hbar*imag(psi'*T*psi);      % Momentum operator
% K = hbar^2/(2*m) * real(psi'*T*psi); % Kinetic energy operator


R = abs(psi).^2;                % Probability density

% Plot wavefunctions and probability density

plot(app.UIAxes,x,psi(:,1),x,psi(:,2),x,psi(:,3));
xlabel(app.UIAxes,'Position (m)');
ylabel(app.UIAxes,'Wavefunction');
legend(app.UIAxes,['E=',num2str(E(1)/1.60217662e-19),' eV'],['E=',num2str(E(2)/1.60217662e-19),' eV'],['E=',num2str(E(3)/1.60217662e-19),' eV']);
title(app.UIAxes,'Wavefunctions');


plot(app.UIAxes2,x,R(:,1),x,R(:,2),x,R(:,3));
xlabel(app.UIAxes2,'Position (m)')
ylabel(app.UIAxes2,'Probability density')
legend(app.UIAxes2,['E=',num2str(E(1)/1.60217662e-19),' eV'],['E=',num2str(E(2)/1.60217662e-19),' eV'],['E=',num2str(E(3)/1.60217662e-19),' eV'])
title(app.UIAxes2,'Probability density')

% % Print expectation values
% fprintf('Expectation values:\n')
% fprintf('  <P> = %e kg*m/s\n',mean(P))
% fprintf('  <K> = %e eV\n',mean(K)/1.60217662e-19)

% S=mean(P);
% app.ExpectationvalueofmomentumEditField.Value=num2str(S(:,n));
%F=mean(K)/1.60217662e-19;
%app.ExpectationvalueofEnergyEditField.Value=num2str(F(:,n));

% %Expectation value
%  x_exp = sum(conj(psi(:,n)).*x.*psi(:,n))*dx;
x_exp = trapz(x, abs(psi(:,n)).^2 .* dx);
 app.ExpectationvalueofpositionEditField.Value=num2str(x_exp);
p_exp =real(sum(conj(psi(:,n)).*fftshift(-1i*hbar*fft(psi(:,n)))/(2*pi).*psi(:,n))*dx);
app.ExpectationvalueofmomentumEditField.Value=num2str(p_exp);
 E_exp = real(sum(conj(psi(:,n)).*(H*psi(:,n)))*dx);
% E_exp = trapz(x, abs(psi(:,n)).^2 .* diag(E));
app.ExpectationvalueofEnergyEditField.Value=num2str(E_exp);


%Energy_level_plot
plot(app.UIAxes3,x,E);
hold(app.UIAxes3,'on');
plot(app.UIAxes3,[x(1), x(end)], [E(n)*1e3, E(n)*1e3], 'k-', 'LineWidth', 2);
xlabel(app.UIAxes3,'Position (m)');
ylabel(app.UIAxes3,'Energy level');
hold(app.UIAxes3,'off');
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Wavefunction')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Probability density')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Energy Level')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SQUAREPOTENTIALWELLLabel = uilabel(app.UIFigure);
            app.SQUAREPOTENTIALWELLLabel.Text = 'SQUARE POTENTIAL WELL';
            app.V0EditFieldLabel = uilabel(app.UIFigure);
            app.V0EditFieldLabel.Text = 'V0';
            app.V0EditField = uieditfield(app.UIFigure, 'text');
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'text');
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'text');
            app.INPUTLabel = uilabel(app.UIFigure);
            app.INPUTLabel.Text = 'INPUT';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.ExpectationvalueofpositionEditFieldLabel = uilabel(app.UIFigure);
            app.ExpectationvalueofpositionEditFieldLabel.Text = 'Expectation  value of position';
            app.ExpectationvalueofpositionEditField = uieditfield(app.UIFigure, 'text');
            app.ExpectationvalueofEnergyEditFieldLabel = uilabel(app.UIFigure);
            app.ExpectationvalueofEnergyEditFieldLabel.Text = 'Expectation  value of Energy';
            app.ExpectationvalueofEnergyEditField = uieditfield(app.UIFigure, 'text');
            app.ExpectationvalueofmomentumEditFieldLabel = uilabel(app.UIFigure);
            app.ExpectationvalueofmomentumEditFieldLabel.Text = 'Expectation  value of momentum';
            app.ExpectationvalueofmomentumEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appinf

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end