classdef appsrodina2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        OutputLabel                    matlab.ui.control.Label
        InputLabel                     matlab.ui.control.Label
        SolutionofSchrodingerEquationforFinitePotentialWellLabel  matlab.ui.control.Label
        EnergyStatenumberEditField     matlab.ui.control.EditField
        EnergyStatenumberEditFieldLabel  matlab.ui.control.Label
        TextField_1                    matlab.ui.control.EditField
        ExpectationvalueofPositionLabel  matlab.ui.control.Label
        TextField_2                    matlab.ui.control.EditField
        ExpectationvalueofEnergyLabel  matlab.ui.control.Label
        TextField_3                    matlab.ui.control.EditField
        ExpectationvalueofmomentumLabel  matlab.ui.control.Label
        LEditField                     matlab.ui.control.EditField
        L1e9EditFieldLabel             matlab.ui.control.Label
        V0EditField                    matlab.ui.control.EditField
        V01e18EditFieldLabel           matlab.ui.control.Label
        SimulateButton                 matlab.ui.control.Button
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            % Get input parameters
V0 = str2double(app.V0EditField.Value)*1e-18;
L = str2double(app.LEditField.Value)*1e-9;
n=str2double(app.EnergyStatenumberEditField.Value);

% Define constants and variables
hbar = 1.0545718e-34; % Planck's constant divided by 2*pi
m = 9.10938356e-31; % electron mass
x = linspace(-3*L, 3*L, 1000); % spatial grid

% Define potential function
V = @(x) x.^2 .* (abs(x) <= L) - V0 * (abs(x) > L);

% V_input = app.VEditField.Value;
% V = str2func(V_input);
% V = V(x);

% Define kinetic energy operator
dx = x(2) - x(1);
N = length(x);
T = zeros(N);
for i = 2:N-1
    T(i,i) = -2;
    T(i,i-1) = 1;
    T(i,i+1) = 1;
end
T(1,1) = -2;
T(1,2) = 1;
T(N,N) = -2;
T(N,N-1) = 1;
T = (-hbar^2/(2*m*dx^2)) * T;

% Define Hamiltonian matrix
H = T + diag(V(x));

% Solve Schrodinger equation
[V, E] = eig(H);

% Normalize wavefunctions
psi = V ./ sqrt(dx);
E = diag(E);

% Plot probability density
P = abs(psi).^2;
plot(app.UIAxes, x, P(:,n));
xlabel(app.UIAxes, 'Position (m)');
ylabel(app.UIAxes, 'Probability density');

% Plot wavefunctions
plot(app.UIAxes2, x, psi(:,1), 'b', x, psi(:,n), 'r');
legend(app.UIAxes2, 'Ground state', 'First excited state');
xlabel(app.UIAxes2, 'Position (m)');
ylabel(app.UIAxes2, 'Wavefunction');


%  calculate expectation values(substitute way)
%     x_exp = trapz(x, abs(psi).^2 .* x);
%     E_exp = trapz(x, abs(psi).^2 .* E);
%     p_exp = trapz(x, abs(psi).^2 .* (-1i * hbar * gradient(psi) / m));

% Compute expectation values
x_exp = trapz(x, abs(psi).^2 .* x);
E_exp = trapz(x, abs(psi).^2 .* diag(E));
p_exp = trapz(x, abs(psi).^2 .* abs(fftshift(fft(psi))).^2);

% Update text fields with expectation values
app.TextField_1.Value = num2str(x_exp(:,n));
app.TextField_2.Value = num2str(E_exp(:,n));
app.TextField_3.Value = num2str(p_exp(:,n));

% % Plot energy level
%     E = E(1:n);
%     plot(app.UIAxes3, [1, 2], [E(n), E(n)], 'k-', 'LineWidth', 2);
%     xlim(app.UIAxes3, [0.5, 2.5]);
%     xticks(app.UIAxes3, [1, 2]);
%     xticklabels(app.UIAxes3, {'', ''});
%     ylabel(app.UIAxes3, 'Energy (J)');
%     title(app.UIAxes3, ['Energy level ', num2str(n)]);

% % Plot energy levels for all states
% E_levels = diag(E);
% plot(app.UIAxes3, 1:length(E_levels), E_levels);
% xlabel(app.UIAxes3, 'State number');
% ylabel(app.UIAxes3, 'Energy (J)');

% Plot energy levels
    n_levels = 5;
    E_levels = diag(E);
    plot(app.UIAxes3, [x(1), x(end)], [E_levels(n,n), E_levels(n,n)], 'k-', 'LineWidth', 2);
    hold(app.UIAxes3, 'on');
    for n = 1:n_levels
        plot(app.UIAxes3, x, E_levels(n)*ones(size(x)), 'b--');
    end
    xlabel(app.UIAxes3, 'Position (m)');
    ylabel(app.UIAxes3, 'Energy (J)');
    ylim(app.UIAxes3, [-V0, max(E_levels(n_levels))]);
    hold(app.UIAxes3, 'off');

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Probability Density')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Wavefunction')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Energy')
            xlabel(app.UIAxes3, 'X')
            ylabel(app.UIAxes3, 'Y')
            zlabel(app.UIAxes3, 'Z')
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.V01e18EditFieldLabel = uilabel(app.UIFigure);
            app.V01e18EditFieldLabel.Text = 'V0(*1e-18)';
            app.V0EditField = uieditfield(app.UIFigure, 'text');
            app.L1e9EditFieldLabel = uilabel(app.UIFigure);
            app.L1e9EditFieldLabel.Text = 'L(*1e-9)';
            app.LEditField = uieditfield(app.UIFigure, 'text');
            app.ExpectationvalueofmomentumLabel = uilabel(app.UIFigure);
            app.ExpectationvalueofmomentumLabel.Text = 'Expectation value of momentum';
            app.TextField_3 = uieditfield(app.UIFigure, 'text');
            app.ExpectationvalueofEnergyLabel = uilabel(app.UIFigure);
            app.ExpectationvalueofEnergyLabel.Text = 'Expectation value of Energy';
            app.TextField_2 = uieditfield(app.UIFigure, 'text');
            app.ExpectationvalueofPositionLabel = uilabel(app.UIFigure);
            app.ExpectationvalueofPositionLabel.Text = 'Expectation value of Position';
            app.TextField_1 = uieditfield(app.UIFigure, 'text');
            app.EnergyStatenumberEditFieldLabel = uilabel(app.UIFigure);
            app.EnergyStatenumberEditFieldLabel.Text = 'Energy State number ';
            app.EnergyStatenumberEditField = uieditfield(app.UIFigure, 'text');
            app.SolutionofSchrodingerEquationforFinitePotentialWellLabel = uilabel(app.UIFigure);
            app.SolutionofSchrodingerEquationforFinitePotentialWellLabel.Text = 'Solution of Schrodinger Equation for Finite Potential Well';
            app.InputLabel = uilabel(app.UIFigure);
            app.InputLabel.Text = 'Input';
            app.OutputLabel = uilabel(app.UIFigure);
            app.OutputLabel.Text = 'Output';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appsrodina2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end