classdef io < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TextField_1              matlab.ui.control.EditField
        ExpectationvalueofpositionLabel  matlab.ui.control.Label
        TextField_3              matlab.ui.control.EditField
        ExpectationvalueofMomentumLabel  matlab.ui.control.Label
        TextField_2              matlab.ui.control.EditField
        ExpectationvalueofLabel  matlab.ui.control.Label
        TextArea                 matlab.ui.control.TextArea
        nEditField               matlab.ui.control.NumericEditField
        nEditFieldLabel          matlab.ui.control.Label
        LEditField               matlab.ui.control.NumericEditField
        LEditFieldLabel          matlab.ui.control.Label
        simulateButton           matlab.ui.control.Button
        UIAxes_prob_density      matlab.ui.control.UIAxes
        UIAxes_energy            matlab.ui.control.UIAxes
        UIAxes_wavefunction      matlab.ui.control.UIAxes
    end

   properties (Access = private)
        Property % Description
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
        end

        % Button pushed function: simulateButton
        function simulateButtonPushed(app, event)
         L = app.LEditField.Value; % Width of the well in meters
n = app.nEditField.Value; % quantum number
N = 1000; % Number of discretization points
m = 9.10938356e-31; % Mass of the electron in kg
hbar = 1.054571817e-34; % Reduced Planck constant in J*s
E0 = (hbar*pi)^2/(2*m*L^2); % Ground state energy of the particle in J
x = linspace(0, L, 1000); % position vector
dx = x(2) - x(1); % step size

% Set up the Hamiltonian matrix
H = -2*eye(N) + diag(ones(N-1,1),1) + diag(ones(N-1,1),-1);
H = H/(dx^2);

% Enforce the boundary conditions
H(1,1) = -1/dx^2;
H(1,2) = 1/dx^2;
H(N,N-1) = 1/dx^2;
H(N,N) = -1/dx^2;

% Diagonalize the Hamiltonian matrix
[V, D] = eig(H);
energies = diag(D)/(2*m/hbar^2);

% Calculate the expectation values of momentum, energy, and position
% for n = 1:5 % Loop over the first five quantum states
    psi = V(:,n)/sqrt(dx); % Use the normalized wavefunction
    P = -1i*hbar*(V*diag(1:N)*V');
    expectation_x = sum(x .* abs(psi).^2) * dx;
    expectation_p = sum(conj(psi).*P(:,n).*psi)*dx;
    expectation_E = sum(conj(psi).*diag(D).*psi)*dx;
    
% end
    % Display the expectation values
    app.TextField_1.Value = num2str(expectation_x(:,n));
    app.TextField_3.Value = num2str(expectation_p);
    app.TextField_2.Value = num2str(expectation_E);

     % Plot the wavefunctions and energies
    plot(app.UIAxes_wavefunction, x, psi);
    xlabel(app.UIAxes_wavefunction, 'Position (m)');
    ylabel(app.UIAxes_wavefunction, 'Wavefunction');
    title(app.UIAxes_wavefunction, ['Wavefunction for n = ', num2str(n)]);
    

plot(app.UIAxes_energy, 1:length(energies), energies/E0, 'o-');
xlabel(app.UIAxes_energy, 'Energy level');
ylabel(app.UIAxes_energy, 'Energy (in units of E_0)');
title(app.UIAxes_energy, 'Energy levels');

% Calculate probability density
            prob_density = abs(psi).^2;

% Plot the probability density function
plot(app.UIAxes_prob_density, x, prob_density);
xlabel(app.UIAxes_prob_density, 'Position (m)');
ylabel(app.UIAxes_prob_density, 'Probability Density');
title(app.UIAxes_prob_density, 'Probability Density Function');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes_wavefunction = uiaxes(app.UIFigure);
            title(app.UIAxes_wavefunction, 'Energy')
            xlabel(app.UIAxes_wavefunction, 'X')
            ylabel(app.UIAxes_wavefunction, 'Y')
            zlabel(app.UIAxes_wavefunction, 'Z')
            app.UIAxes_energy = uiaxes(app.UIFigure);
            title(app.UIAxes_energy, 'momentum')
            xlabel(app.UIAxes_energy, 'X')
            ylabel(app.UIAxes_energy, 'Y')
            zlabel(app.UIAxes_energy, 'Z')
            app.UIAxes_prob_density = uiaxes(app.UIFigure);
            title(app.UIAxes_prob_density, 'probability density')
            xlabel(app.UIAxes_prob_density, 'X')
            ylabel(app.UIAxes_prob_density, 'Y')
            zlabel(app.UIAxes_prob_density, 'Z')
            app.simulateButton = uibutton(app.UIFigure, 'push');
            app.simulateButton.ButtonPushedFcn = createCallbackFcn(app, @simulateButtonPushed, true);
            app.simulateButton.Text = 'simulate';
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.nEditFieldLabel = uilabel(app.UIFigure);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.UIFigure, 'numeric');
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.Value = {'Electron in a box for two infinite potential well'};
            app.ExpectationvalueofLabel = uilabel(app.UIFigure);
            app.ExpectationvalueofLabel.Text = 'Expectation value of  Energy';
            app.TextField_2 = uieditfield(app.UIFigure, 'text');
            app.ExpectationvalueofMomentumLabel = uilabel(app.UIFigure);
            app.ExpectationvalueofMomentumLabel.Text = 'Expectation value of Momentum ';
            app.TextField_3 = uieditfield(app.UIFigure, 'text');
            app.ExpectationvalueofpositionLabel = uilabel(app.UIFigure);
            app.ExpectationvalueofpositionLabel.Text = 'Expectation  value of position';
            app.TextField_1 = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = io

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end